classdef annotate_fluoro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        NumberInterpEditField         matlab.ui.control.NumericEditField
        NumberInterpEditFieldLabel    matlab.ui.control.Label
        LoadFluoroButton              matlab.ui.control.Button
        SubjectEditField              matlab.ui.control.EditField
        SubjectEditFieldLabel         matlab.ui.control.Label
        StartatEditField              matlab.ui.control.NumericEditField
        StartatEditFieldLabel         matlab.ui.control.Label
        ContactsPrefixEditField       matlab.ui.control.EditField
        ContactsPrefixEditFieldLabel  matlab.ui.control.Label
        MarkElectrodeSplineButton     matlab.ui.control.Button
        MarkLandmarkButton            matlab.ui.control.Button
        LandmarkNameDropDown          matlab.ui.control.DropDown
        LandmarkNameDropDownLabel     matlab.ui.control.Label
        HemiDropDown                  matlab.ui.control.DropDown
        HemiDropDownLabel             matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        SaveLandmarksButton           matlab.ui.control.Button
        LoadLandmarksButton           matlab.ui.control.Button
        DeleteRowsButton              matlab.ui.control.Button
        MessageBoxTextArea            matlab.ui.control.TextArea
        MessageBoxTextAreaLabel       matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        
        function [] = AppendToTable(app, s)
            T = app.UITable.Data; 
            T = bml_annot_rowbind(T, struct2table(s)); 
            T = sortrows(T, "name", "descend"); 

            set(T.hP, 'LabelVisible', 'hover');

            app.UITable.Data = T; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFluoroButton
        function LoadFluoroButtonPushed(app, event)
            try 
                I_full = imread("Y:\DBS\derivatives\sub-DM10XX\ecogloc\sub-DM1010_ses-intraop_fluoro-L.tiff");
            catch
                f = uigetfile(); 
                I_full = imread(f);
            end
            I = I_full(:, :, 3);

            image(app.UIAxes, [-1 1], [1 -1], I);
            colormap(app.UIAxes, "gray")
            axis(app.UIAxes, 'square')
            axis(app.UIAxes, 'tight')
            set(app.UIAxes, 'ydir','normal'); 
        end

        % Button pushed function: MarkElectrodeSplineButton
        function MarkElectrodeSplineButtonPushed(app, event)
            %%
            app.UIFigure.HandleVisibility = 'on'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
    
            axes(app.UIAxes)
            [x, y] = ginput(); 
            spcv = cscvn( [x, y].');
            hold on
            % fnplt( spcv )
            shg


            % Define the number of points for interpolation
            num_points = app.NumberInterpEditField.Value;
%             input("how many elecs?");
%             input("prefix?");


            % Get the breaks (knots) of the spline
            knots = spcv.breaks;

            % Create equally spaced points between the first and last knot
            interp_points = linspace(knots(1), knots(end), num_points);

            % Evaluate the spline at the interpolated points
            interp_values = fnval(spcv, interp_points);

            contact_number_string = string(compose('%02d', (1:num_points)-1 + app.StartatEditField.Value));

            % Extract x and y coordinates
            for ielec = 1:num_points
                s = []; 
                s.hemi = "testing"; 
                s.name = string(app.ContactsPrefixEditField.Value)+ contact_number_string(ielec); 
%                 s.pos_1 = interp_values(1, ielec);  
%                 s.pos_2 = interp_values(2, ielec); 
                s.hP = drawpoint(app.UIAxes, 'Position', [interp_values(1, ielec) interp_values(2, ielec)]); s.hP.Label = s.name;
                AppendToTable(app, s)

            end

            app.StartatEditField.Value = app.StartatEditField.Value + app.NumberInterpEditField.Value; 
        end

        % Button pushed function: MarkLandmarkButton
        function MarkLandmarkButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'on'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
    
            axes(app.UIAxes)
            [x, y] = ginput(); 
            x = x(1); y = y(1); 
            hold on
            
            s = []; 
            s.hemi = string(app.HemiDropDown.Value); 
            s.name = string(app.LandmarkNameDropDown.Value); 
%             s.pos_1 = x; 
%             s.pos_2 = y; 
            s.hP = drawpoint(app.UIAxes, 'Position', [x y]); s.hP.Label = s.name;
            AppendToTable(app, s)


        end

        % Button pushed function: DeleteRowsButton
        function DeleteRowsButtonPushed(app, event)
            idxs = app.UITable.Selection;
            delete(app.UITable.Data.hP(idxs)); 
            app.UITable.Data(idxs, :) = []; 
            % app.UITable.Data.hP()
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            idxs = false(1, height(app.UITable.Data)); 
            idxs(app.UITable.Selection) = true;
            set(app.UITable.Data.hP(idxs), "Color", 'r', 'LabelVisible', 'on'); 
            set(app.UITable.Data.hP(~idxs), "Color", [0 0.4470 0.7410], 'LabelVisible', 'hover'); 

        end

        % Button pushed function: SaveLandmarksButton
        function SaveLandmarksButtonPushed(app, event)
            T = app.UITable.Data;
            T.pos_1 = arrayfun(@(hP) hP.Position(1), T.hP);
            T.pos_2 = arrayfun(@(hP) hP.Position(2), T.hP);

            writetable(T, app.Paths.LandmarksTablePath)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {908, 908};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HemiDropDownLabel = uilabel(app.LeftPanel);
            app.HemiDropDownLabel.Text = 'Hemi';
            app.HemiDropDown = uidropdown(app.LeftPanel);
            app.HemiDropDown.Items = {'uncertain', 'left', 'right'};
            app.HemiDropDown.Value = 'uncertain';
            app.LandmarkNameDropDownLabel = uilabel(app.LeftPanel);
            app.LandmarkNameDropDownLabel.Text = 'Landmark Name';
            app.LandmarkNameDropDown = uidropdown(app.LeftPanel);
            app.LandmarkNameDropDown.Items = {'pintip_occ', 'pintip_front', 'dbslead_top', 'dbslad_bottom'};
            app.LandmarkNameDropDown.Value = 'pintip_occ';
            app.MarkLandmarkButton = uibutton(app.LeftPanel, 'push');
            app.MarkLandmarkButton.ButtonPushedFcn = createCallbackFcn(app, @MarkLandmarkButtonPushed, true);
            app.MarkLandmarkButton.Text = 'Mark Landmark';
            app.MarkElectrodeSplineButton = uibutton(app.LeftPanel, 'push');
            app.MarkElectrodeSplineButton.ButtonPushedFcn = createCallbackFcn(app, @MarkElectrodeSplineButtonPushed, true);
            app.MarkElectrodeSplineButton.Text = 'Mark Electrode Spline';
            app.ContactsPrefixEditFieldLabel = uilabel(app.LeftPanel);
            app.ContactsPrefixEditFieldLabel.Text = 'Contacts Prefix';
            app.ContactsPrefixEditField = uieditfield(app.LeftPanel, 'text');
            app.ContactsPrefixEditField.Value = 'ecog_L1';
            app.StartatEditFieldLabel = uilabel(app.LeftPanel);
            app.StartatEditFieldLabel.Text = 'Start at';
            app.StartatEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartatEditField.Value = 1;
            app.SubjectEditFieldLabel = uilabel(app.LeftPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.LeftPanel, 'text');
            app.SubjectEditField.Value = 'DM1001';
            app.LoadFluoroButton = uibutton(app.LeftPanel, 'push');
            app.LoadFluoroButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFluoroButtonPushed, true);
            app.LoadFluoroButton.Text = 'Load Fluoro';
            app.NumberInterpEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberInterpEditFieldLabel.Text = 'Number Interp';
            app.NumberInterpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberInterpEditField.Value = 21;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'hemi'; 'name'; 'hP'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.MessageBoxTextAreaLabel = uilabel(app.RightPanel);
            app.MessageBoxTextAreaLabel.Text = 'Message Box';
            app.MessageBoxTextArea = uitextarea(app.RightPanel);
            app.DeleteRowsButton = uibutton(app.RightPanel, 'push');
            app.DeleteRowsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowsButtonPushed, true);
            app.DeleteRowsButton.Text = 'Delete Rows';
            app.LoadLandmarksButton = uibutton(app.RightPanel, 'push');
            app.LoadLandmarksButton.Text = 'Load Landmarks';
            app.SaveLandmarksButton = uibutton(app.RightPanel, 'push');
            app.SaveLandmarksButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLandmarksButtonPushed, true);
            app.SaveLandmarksButton.Text = 'Save Landmarks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotate_fluoro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end